classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CurrentAngleofAttackGauge       matlab.ui.control.SemicircularGauge
        CurrentAngleofAttackGaugeLabel  matlab.ui.control.Label
        TargetAoAKnob                   matlab.ui.control.Knob
        TargetAoAKnobLabel              matlab.ui.control.Label
        TargetAoAEditField              matlab.ui.control.NumericEditField
        TargetAoAEditFieldLabel         matlab.ui.control.Label
        WindSpeedGauge                  matlab.ui.control.Gauge
        WindSpeedGaugeLabel             matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        ArtificialHorizon               Aero.ui.control.ArtificialHorizon
        WindSpeedUnitsButtonGroup       matlab.ui.container.ButtonGroup
        KnotsButton                     matlab.ui.control.RadioButton
        msButton                        matlab.ui.control.RadioButton
        mphButton                       matlab.ui.control.RadioButton
        ftsButton                       matlab.ui.control.RadioButton
        AoAWarningLampLabel             matlab.ui.control.Label
        AoAWarningLamp                  matlab.ui.control.Lamp
        DiagnosticTroubleshootingDataLabel  matlab.ui.control.Label
        UIAxes7                         matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes8                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: TargetAoAKnob
        function TargetAoAKnobValueChanged(app, event)
            app.TargetAoAEditField.Value = app.TargetAoAKnob.Value;
            app.ArtificialHorizon.Pitch = app.TargetAoAKnob.Value;
        end

        % Value changed function: TargetAoAEditField
        function TargetAoAEditFieldValueChanged(app, event)
            app.TargetAoAKnob.Value = app.TargetAoAEditField.Value;
            app.ArtificialHorizon.Pitch = app.TargetAoAEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CurrentAngleofAttackGauge = uigauge(app.UIFigure, 'semicircular');
            app.CurrentAngleofAttackGauge.Limits = [-90 90];
            app.CurrentAngleofAttackGaugeLabel = uilabel(app.UIFigure);
            app.CurrentAngleofAttackGaugeLabel.Text = 'Current Angle of Attack';
            app.TargetAoAKnob = uiknob(app.UIFigure, 'continuous');
            app.TargetAoAKnob.Limits = [-90 90];
            app.TargetAoAKnob.ValueChangedFcn = createCallbackFcn(app, @TargetAoAKnobValueChanged, true);
            app.TargetAoAKnobLabel = uilabel(app.UIFigure);
            app.TargetAoAKnobLabel.Text = 'Target AoA';
            app.TargetAoAEditField = uieditfield(app.UIFigure, 'numeric');
            app.TargetAoAEditField.Limits = [-90 90];
            app.TargetAoAEditField.ValueDisplayFormat = '%.1f';
            app.TargetAoAEditField.ValueChangedFcn = createCallbackFcn(app, @TargetAoAEditFieldValueChanged, true);
            app.TargetAoAEditFieldLabel = uilabel(app.UIFigure);
            app.TargetAoAEditFieldLabel.Text = 'Target AoA';
            app.WindSpeedGauge = uigauge(app.UIFigure, 'circular');
            app.WindSpeedGauge.Limits = [0 35];
            app.WindSpeedGaugeLabel = uilabel(app.UIFigure);
            app.WindSpeedGaugeLabel.Text = 'Wind Speed';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.ArtificialHorizon = uiaerohorizon(app.UIFigure);
            app.WindSpeedUnitsButtonGroup = uibuttongroup(app.UIFigure);
            app.WindSpeedUnitsButtonGroup.Title = 'Wind Speed Units';
            app.KnotsButton = uiradiobutton(app.WindSpeedUnitsButtonGroup);
            app.KnotsButton.Text = 'Knots';
            app.KnotsButton.Value = true;
            app.msButton = uiradiobutton(app.WindSpeedUnitsButtonGroup);
            app.msButton.Text = 'm/s';
            app.mphButton = uiradiobutton(app.WindSpeedUnitsButtonGroup);
            app.mphButton.Text = 'mph';
            app.ftsButton = uiradiobutton(app.WindSpeedUnitsButtonGroup);
            app.ftsButton.Text = 'ft/s';
            app.AoAWarningLampLabel = uilabel(app.UIFigure);
            app.AoAWarningLampLabel.Text = 'AoA Warning';
            app.AoAWarningLamp = uilamp(app.UIFigure);
            app.DiagnosticTroubleshootingDataLabel = uilabel(app.UIFigure);
            app.DiagnosticTroubleshootingDataLabel.Text = 'Diagnostic/Troubleshooting Data';
            app.UIAxes7 = uiaxes(app.UIFigure);
            title(app.UIAxes7, 'L Drag Sensor')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Force (N)')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'R Drag Sensor')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Force (N)')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'R Sideslip Sensor')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'L Sideslip Sensor')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'R Lift Sensor')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Force (N)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Center Lift Sensor')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Force (N)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'L Lift Sensor')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Force (N)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes8 = uiaxes(app.UIFigure);
            title(app.UIAxes8, 'Wind Speed')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end